classdef MATBreaker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        RestartBtn  matlab.ui.control.Button
        Startbtn    matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    %Propiedades del juego
    properties (Access = private)
    paleta
    pelota
    dx = 1
    dy = 1
    timer
    juegoActivo = false; % indica si el juego está en curso

 % Description
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
        
    function moverPelota(app)
        pos = app.pelota.Position;
        nuevaX = pos(1) + app.dx;
        nuevaY = pos(2) + app.dy;
    
        % Rebotar en los bordes izquierdo/derecho
        if nuevaX <= 0 || nuevaX + pos(3) >= 100
            app.dx = -app.dx;
        end
    
        % Rebotar en el borde superior
        if nuevaY + pos(4) >= 100
            app.dy = -app.dy;
        end
    
        % Rebotar con la paleta
        paletaPos = app.paleta.Position;
        if nuevaY <= paletaPos(2) + paletaPos(4) && ...
           nuevaX + pos(3) >= paletaPos(1) && ...
           nuevaX <= paletaPos(1) + paletaPos(3)
            app.dy = abs(app.dy); % rebote hacia arriba
        end
    
        % Verificar si la pelota se cae
            if nuevaY <= 0
                stop(app.timer);
                app.juegoActivo = false; % Desactiva el movimiento
                uialert(app.UIFigure, '¡Perdiste!', 'Fin del juego');
                app.Startbtn.Visible = 'on';
                app.RestartBtn.Visible = 'on';
                return;
            end

    
        % Mover pelota
        app.pelota.Position = [nuevaX, nuevaY, pos(3), pos(4)];
    end


        %mover la paleta
        function moverPaleta(app, event)

            if ~app.juegoActivo
                return; % No hace nada si no está activo el juego
            end
        
            tecla = event.Key;
            pos = app.paleta.Position;
        
            if strcmp(tecla, 'leftarrow') && pos(1) > 0
                pos(1) = pos(1) - 2;
            elseif strcmp(tecla, 'rightarrow') && pos(1) + pos(3) < 100
                pos(1) = pos(1) + 2;
            end
        
            app.paleta.Position = pos;

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% Configurar el área de juego
% Establecer límites del UIAxes
xlim(app.UIAxes, [0 100]);
ylim(app.UIAxes, [0 100]);

% Quitar ticks
app.UIAxes.XTick = [];
app.UIAxes.YTick = [];

% Fondo negro o el que prefieras
app.UIAxes.Color = 'k';

axis(app.UIAxes, 'manual');
hold(app.UIAxes, 'on');

% Crear la paleta
app.paleta = rectangle(app.UIAxes, ...
    'Position', [45, 5, 15, 2], ...
    'FaceColor', 'w', ...
    'HitTest', 'off', ...
    'PickableParts', 'none');  % <-- evita interacción con mouse

% Crear la pelota
app.pelota = rectangle(app.UIAxes, ...
    'Position', [50, 10, 2, 2], ...
    'Curvature', [1 1], ...
    'FaceColor', 'r', ...
    'HitTest', 'off', ...
    'PickableParts', 'none');  % <-- evita interacción con mouse


% Crear el temporizador
app.timer = timer( ...
    'ExecutionMode', 'fixedRate', ...
    'Period', 0.01, ...
    'TimerFcn', @(~,~)moverPelota(app));

% Detectar teclas (izquierda/derecha)
set(app.UIFigure, 'KeyPressFcn', @(src, event) moverPaleta(app, event)); 


        end

        % Button pushed function: Startbtn
        function StartbtnButtonPushed(app, event)

    app.Startbtn.Visible = 'off';
    app.RestartBtn.Visible = 'off';
    app.juegoActivo = true;

    % Iniciar el juego
    start(app.timer);

    % Forzar el foco al UIFigure usando Java
    drawnow;
    pause(0.05);
    try
        warning('off', 'MATLAB:HandleGraphics:ObsoletedProperty:JavaFrame');
        jFrame = get(handle(app.UIFigure), 'JavaFrame');
        jFrame.fHG1Client.setFocus;
    catch
        % En caso de fallo, no hace nada (por ejemplo en MATLAB Online)
    end


        end

        % Button pushed function: RestartBtn
        function RestartBtnButtonPushed(app, event)
% Button pushed function: RestartBtn

    if isvalid(app.timer) && strcmp(app.timer.Running, 'on')
        stop(app.timer);
        
    end

    % Reiniciar estado del juego
    app.paleta.Position = [45, 5, 15, 2];
    app.pelota.Position = [50, 10, 2, 2];
    app.dx = 1;
    app.dy = 1;

    app.Startbtn.Visible = 'off';
    app.RestartBtn.Visible = 'off';
    app.juegoActivo = true;

    % Iniciar el juego
    start(app.timer);


    % Forzar foco al UIFigure
    drawnow;
    pause(0.05);
    app.UIFigure.Visible = 'off';
    app.UIFigure.Visible = 'on';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Startbtn = uibutton(app.UIFigure, 'push');
            app.Startbtn.ButtonPushedFcn = createCallbackFcn(app, @StartbtnButtonPushed, true);
            app.Startbtn.Text = 'Iniciar';
            app.RestartBtn = uibutton(app.UIFigure, 'push');
            app.RestartBtn.ButtonPushedFcn = createCallbackFcn(app, @RestartBtnButtonPushed, true);
            app.RestartBtn.Text = 'Reiniciar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATBreaker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end